classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PGAINEditField                matlab.ui.control.NumericEditField
        PGAINEditFieldLabel           matlab.ui.control.Label
        DGAINEditField                matlab.ui.control.NumericEditField
        DGAINLabel                    matlab.ui.control.Label
        IGAINEditField                matlab.ui.control.NumericEditField
        IGAINLabel                    matlab.ui.control.Label
        ReferenceInputEditField       matlab.ui.control.NumericEditField
        ReferenceInputEditFieldLabel  matlab.ui.control.Label
        SystemResponseEditField       matlab.ui.control.NumericEditField
        SystemResponseEditFieldLabel  matlab.ui.control.Label
        AnalogVoltageEditField        matlab.ui.control.NumericEditField
        AnalogVoltageEditFieldLabel   matlab.ui.control.Label
        ConnectButton                 matlab.ui.control.Button
        DisconnectButton              matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

 properties (Access = private)
     
          Response         % variable y for the system's response
          s                % Serial
          ctr              % Counter to store the elements in arrays for plot
   
 end
 
    methods (Access = private)  
        function ReadInputDataOverSerial(app, hObject, eventdata, handles)
            
            %Read 
            Values=fread(app.s,[1,app.s.BytesAvailable],'char')       
           if(~isempty(Values))    
            
           
            Analog_Voltage     = Values(1);   
            Reference_Input    = Values(3);
            System_Response    = Values(5);
            
            %Display in GUI 
            app.SystemResponseEditField.Value = System_Response; 
            app.AnalogVoltageEditField.Value =  Analog_Voltage;
            app.ReferenceInputEditField.Value = Reference_Input;
            
            %Store
            app.Response{app.ctr} = System_Response;
            app.ctr = app.ctr + 1;
       
           end

            %Plot for system response
            x = 1:size(app.Response,2);       
            y = zeros(1,size(app.Response,2));
            for i=1:size(y,2)
                y(i) = app.Response{i};
            end
            if (mod(app.ctr,5)==0)
            plot(app.UIAxes,x,y,'LineWidth',1.5);
            end

        end
        
 end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Response = cell(1,1);  %Initialize the variable
            app.ctr = 1;               %Set the counter to 1
        end

        % Value changed function: PGAINEditField
        function PGAINEditFieldValueChanged(app, event)
            value = app.PGAINEditField.Value;   
            fwrite(app.s,value)              % input to be able to enter a value.
        end

        % Value changed function: IGAINEditField
        function IGAINEditFieldValueChanged(app, event)
            value = app.IGAINEditField.Value;
           fwrite(app.s,value)                % input to be able to enter a value.
        end

        % Value changed function: DGAINEditField
        function DGAINEditFieldValueChanged(app, event)
            value = app.DGAINEditField.Value;
            fwrite(app.s,value)               % input to be able to enter a value.
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.s=serial('COM3','BaudRate',9600);   
            app.s.BytesAvailableFcn={@app.ReadInputDataOverSerial};
            app.s.BytesAvailableFcnCount = 7;
            app.s.BytesAvailableFcnMode='byte';
            fopen(app.s);     % Open The Gui
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
           fclose(app.s);      % stop the Gui
        end

        % Value changed function: ReferenceInputEditField
        function ReferenceInputEditFieldValueChanged(app, event)
            value = app.ReferenceInputEditField.Value;
            fwrite(app.s,value)            % input to be able to enter a value.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'System Response')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Pot Value')
            zlabel(app.UIAxes, 'Z')
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.AnalogVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.AnalogVoltageEditFieldLabel.Text = 'Analog Voltage';
            app.AnalogVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.SystemResponseEditFieldLabel = uilabel(app.UIFigure);
            app.SystemResponseEditFieldLabel.Text = 'System Response';
            app.SystemResponseEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReferenceInputEditFieldLabel = uilabel(app.UIFigure);
            app.ReferenceInputEditFieldLabel.Text = 'Reference Input';
            app.ReferenceInputEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReferenceInputEditField.ValueChangedFcn = createCallbackFcn(app, @ReferenceInputEditFieldValueChanged, true);
            app.IGAINLabel = uilabel(app.UIFigure);
            app.IGAINLabel.Text = 'I GAIN :';
            app.IGAINEditField = uieditfield(app.UIFigure, 'numeric');
            app.IGAINEditField.ValueChangedFcn = createCallbackFcn(app, @IGAINEditFieldValueChanged, true);
            app.DGAINLabel = uilabel(app.UIFigure);
            app.DGAINLabel.Text = 'D GAIN :';
            app.DGAINEditField = uieditfield(app.UIFigure, 'numeric');
            app.DGAINEditField.ValueChangedFcn = createCallbackFcn(app, @DGAINEditFieldValueChanged, true);
            app.PGAINEditFieldLabel = uilabel(app.UIFigure);
            app.PGAINEditFieldLabel.Text = 'P GAIN :';
            app.PGAINEditField = uieditfield(app.UIFigure, 'numeric');
            app.PGAINEditField.ValueChangedFcn = createCallbackFcn(app, @PGAINEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end